classdef Controls_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Controls                matlab.ui.Figure
        uipanel1                matlab.ui.container.Panel
        s_ceil                  matlab.ui.control.Slider
        s_floor                 matlab.ui.control.Slider
        s_alpha                 matlab.ui.control.Slider
        m_channelPicker         matlab.ui.control.DropDown
        pb_colorChooser         matlab.ui.control.Button
        cb_visible              matlab.ui.control.CheckBox
        text13                  matlab.ui.control.Label
        text14                  matlab.ui.control.Label
        text15                  matlab.ui.control.Label
        text16                  matlab.ui.control.Label
        s_mids                  matlab.ui.control.Slider
        tb_title                matlab.ui.control.Label
        uipanel3                matlab.ui.container.Panel
        text7                   matlab.ui.control.Label
        text8                   matlab.ui.control.Label
        text9                   matlab.ui.control.Label
        text10                  matlab.ui.control.Label
        text11                  matlab.ui.control.Label
        text12                  matlab.ui.control.Label
        tb_curFrame             matlab.ui.control.Label
        tb_numFrames            matlab.ui.control.Label
        tb_numChan              matlab.ui.control.Label
        tb_zDim                 matlab.ui.control.Label
        tb_yDim                 matlab.ui.control.Label
        tb_xDim                 matlab.ui.control.Label
        text25                  matlab.ui.control.Label
        text26                  matlab.ui.control.Label
        text27                  matlab.ui.control.Label
        tb_phyZ                 matlab.ui.control.Label
        tb_phyY                 matlab.ui.control.Label
        tb_phyX                 matlab.ui.control.Label
        uipanel4                matlab.ui.container.Panel
        cb_textureLighting      matlab.ui.control.CheckBox
        cb_segLighting          matlab.ui.control.CheckBox
        cb_Play                 matlab.ui.control.CheckBox
        cb_Rotate               matlab.ui.control.CheckBox
        pb_ResetView            matlab.ui.control.Button
        cb_Wireframe            matlab.ui.control.CheckBox
        cb_SegmentationResults  matlab.ui.control.CheckBox
        cb_atten                matlab.ui.control.CheckBox
        pannel_buffer           matlab.ui.container.Panel
        sw_buffer               matlab.ui.control.Switch
        pb_saveSettings         matlab.ui.control.Button
        pb_loadSettings         matlab.ui.control.Button
        text17                  matlab.ui.control.Label
        s_curFrame              matlab.ui.control.Slider
        funcPlot                matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function Controls_CloseRequestFcn(~, hObject, ~, handles)
            global EXT_MESAGE_FUNC D3dIsOpen
            if (~isempty(EXT_MESAGE_FUNC))
                msg.command = 'close';
                EXT_MESAGE_FUNC(msg);
            end
            D3d.Close();
            delete(hObject);
            D3dIsOpen = false;
        end
        
        function cb_Play_Callback(app, hObject, eventdata, handles)
            on = get(handles.cb_Play,'Value');
            D3d.Viewer.Play(on);
            D3d.Update();
        end
        
        function cb_Rotate_Callback(~, hObject, eventdata, handles)
            on = get(handles.cb_Rotate,'Value');
            D3d.Viewer.Spin(on);
            D3d.Update();
        end
        
        function cb_SegmentationResults_Callback(app, hObject, eventdata, handles)
            on = get(handles.cb_SegmentationResults,'Value');
            D3d.Viewer.ShowAllPolygons(on);
            D3d.Update();
        end
        
        function cb_ShowLabels_Callback(app, hObject, eventdata, handles)
            on = get(handles.cb_ShowLabels,'Value');
            D3d.Viewer.ShowLabels(on);
            D3d.Update();
        end
        
        function cb_Wireframe_Callback(app, hObject, eventdata, handles)
            on = get(handles.cb_Wireframe,'Value');
            D3d.Viewer.ToggleWireframe(on);
            D3d.Update();
        end
        
        function cb_atten_Callback(app, hObject, eventdata, handles)
            D3d.Viewer.TextureAttenuation(get(handles.cb_atten,'Value'));
            D3d.Update();
        end
        
        function cb_segLighting_Callback(app, hObject, eventdata, handles)
            on = get(handles.cb_segLighting,'Value');
            D3d.Viewer.PolygonLighting(on);
            D3d.Update();
        end
        
        function cb_textureLighting_Callback(app, hObject, eventdata, handles)
            D3d.Viewer.TextureLighting(get(handles.cb_textureLighting,'Value'));
            D3d.Update();
        end
        
        function cb_visible_Callback(app, hObject, eventdata, handles)
                D3d.UI.ToggleChannel(get(handles.m_channelPicker,'Value'),get(handles.cb_visible,'Value'));
        end
        
        function m_channelPicker_Callback(app, hObject, eventdata, handles)
            D3d.UI.Ctrl.UpdateCurrentState();
        end
        
        function m_channelPicker_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to m_channelPicker (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function pb_ResetView_Callback(app, hObject, eventdata, handles)
            D3d.Viewer.ResetView();
            D3d.UI.Ctrl.ResetTransferFunctions();
            D3d.Update();
        end
        
        function pb_colorChooser_Callback(app, hObject, eventdata, handles)
            [imageData, colors, channelData] = D3d.UI.Ctrl.GetUserData();
            
            chan = get(handles.m_channelPicker,'Value');
            channelData(chan).color = uisetcolor(channelData(chan).color);
            colors(chan,:) = channelData(chan).color;
            
            D3d.UI.Ctrl.SetUserData(imageData,colors,channelData);
            D3d.UI.Ctrl.UpdateCurrentState();
            D3d.UI.Ctrl.PlotTransferFunctions();
        end
        
        function pb_loadSettings_Callback(app, hObject, eventdata, handles)
            D3d.LoadTransferFunction();
        end
        
        function pb_saveSettings_Callback(app, hObject, eventdata, handles)
            D3d.SaveTransferFunction();
        end
        
        function s_alpha_Callback(app, hObject, eventdata, handles)
            [imageData, colors, channelData] = D3d.UI.Ctrl.GetUserData();
            
            chan = get(handles.m_channelPicker,'Value');
            channelData(chan).alphaMod = get(handles.s_alpha,'Value');
            
            D3d.UI.Ctrl.SetUserData(imageData,colors,channelData)
            D3d.UI.Ctrl.PlotTransferFunctions();
        end
        
        function s_alpha_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to s_alpha (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function s_ceil_Callback(app, hObject, eventdata, handles)
            [imageData, colors, channelData] = D3d.UI.Ctrl.GetUserData();
            chan = get(handles.m_channelPicker,'Value');
            mxVal = get(handles.s_ceil,'Value');
            mnVal = get(handles.s_floor,'Value');
            
            if (mxVal<0.01)
                mxVal = 0.01;
            end
            
            if (mxVal<=mnVal+0.01)
                mnVal = mxVal-0.015;
                if (mnVal<0)
                    mnVal = 0;
                    mxVal = 0.01;
                    set(handles.s_ceil,'Value',mxVal);
                end
                set(handles.s_floor,'Value',mnVal);
                channelData(chan).minVal = mnVal;
            end
            channelData(chan).maxVal = mxVal;
            
            D3d.UI.Ctrl.SetUserData(imageData,colors,channelData);
            D3d.UI.Ctrl.PlotTransferFunctions();
        end
        
        function s_ceil_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to s_ceil (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function s_curFrame_Callback(app, hObject, eventdata, handles)
            frame = round(get(handles.s_curFrame,'Value'));
            D3d.UI.Ctrl.UpdateTime(frame);
            D3d.Update();
        end
        
        function s_curFrame_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to s_curFrame (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function s_floor_Callback(app, hObject, eventdata, handles)
            [imageData, colors, channelData] = D3d.UI.Ctrl.GetUserData();
            
            chan = get(handles.m_channelPicker,'Value');
            mxVal = get(handles.s_ceil,'Value');
            mnVal = get(handles.s_floor,'Value');
            
            if (mnVal>0.99)
                mnVal = 0.99;
            end
            
            if (mxVal<=mnVal+0.01)
                mxVal = mnVal+0.015;
                if (mxVal>1)
                    mxVal = 1;
                    mnVal = 0.99;
                    set(handles.s_floor,'Value',mnVal);
                end
                set(handles.s_ceil,'Value',mxVal);
                channelData(chan).maxVal = mxVal;
            end
            channelData(chan).minVal = mnVal;
            
            D3d.UI.Ctrl.SetUserData(imageData,colors,channelData)
            D3d.UI.Ctrl.PlotTransferFunctions();
        end
        
        function s_floor_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to s_floor (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function s_mids_Callback(app, hObject, eventdata, handles)
            [imageData, colors, channelData] = D3d.UI.Ctrl.GetUserData();
            
            chan = get(handles.m_channelPicker,'Value');
            channelData(chan).midVal = get(handles.s_mids,'Value');
            
            D3d.UI.Ctrl.SetUserData(imageData,colors,channelData)
            D3d.UI.Ctrl.PlotTransferFunctions();
        end
        
        function s_mids_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to s_mids (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function setupData(app, hObject, handles, varargin)
            global D3dUICtrlHandles D3dIsOpen
            if (nargin<3)
                error('No image metadata sent!');
            end
            
            D3dUICtrlHandles = struct('handles',handles,'hObject',hObject);
            
            imageData = varargin{1}{1};
            
            savedData = [];
            
            if (exist([imageData.DatasetName '_Settings.mat'],'file'))
                savedData = load([imageData.DatasetName '_Settings.mat']);
            end
            
            if (isfield(imageData,'ChannelColors') && ~isempty(imageData.ChannelColors))
                colors = imageData.ChannelColors;
            else
                colors = [[0.0, 1.0, 0.0];...
                    [1.0, 0.0, 0.0];...
                    [0.0, 0.0, 1.0];...
                    [1.0, 1.0, 0.0];...
                    [0.0, 1.0, 1.0];...
                    [1.0, 0.0, 1.0]];
            end
            
            localStruct = struct(...
                'color',[0,0,0],...
                'visible',1,...
                'alphaMod',1.0,...
                'minVal',0.0,...
                'midVal',0.5,...
                'maxVal',1.0,...
                'a',0.0,...
                'b',1.0,...
                'c',0.0);
            
            if (~isfield(savedData,'channelData') || isempty(savedData.channelData))
                channelData = localStruct;
                for i=1:imageData.NumberOfChannels
                    if (imageData.NumberOfChannels==1)
                        localStruct.color = [1,1,1];
                        colors(1,:) = [1,1,1];
                    else
                        localStruct.color = colors(i,:);
                    end
                    channelData(i) = localStruct;
                end
            else
                channelData = savedData.channelData;
                colors = savedData.colors;
            end
            
            if (imageData.NumberOfChannels>0)
                if (isfield(imageData,'ChannelColor') && ~isempty(imageData.ChannelColor))
                    channelData.color = imageData.ChannelColor;
                end
                if (isfield(imageData,'ChannelNames') && ~isempty(imageData.ChannelNames))
                    strng = imageData.ChannelNames;
                else
                    for i=1:imageData.NumberOfChannels
                        if i==1
                            strng = {sprintf('Channel %d',i)};
                        else
                            strng = [strng; {sprintf('Channel %d',i)}];
                        end
                    end
                end
            end
            
            D3d.UI.Ctrl.SetUserData(imageData,colors,channelData);
            
            set(handles.m_channelPicker,'string',strng);
            set(handles.tb_numFrames,'string',num2str(imageData.NumberOfFrames));
            if (imageData.NumberOfFrames > 1)
                set(handles.s_curFrame,'Max',imageData.NumberOfFrames,'Min',1,'Enable','on','SliderStep',[1/imageData.NumberOfFrames, 0.1],'Value',1);
            else
                set(handles.s_curFrame,'enable','off');
            end
            set(handles.tb_numChan,'string',num2str(imageData.NumberOfChannels));
            set(handles.tb_title,'string',imageData.DatasetName);
            set(handles.tb_curFrame,'string',num2str(1));
            set(handles.tb_xDim,'string',num2str(imageData.Dimensions(1)));
            set(handles.tb_yDim,'string',num2str(imageData.Dimensions(2)));
            set(handles.tb_zDim,'string',num2str(imageData.Dimensions(3)));
            set(handles.tb_phyX,'string',num2str(imageData.PixelPhysicalSize(1)));
            set(handles.tb_phyY,'string',num2str(imageData.PixelPhysicalSize(2)));
            set(handles.tb_phyZ,'string',num2str(imageData.PixelPhysicalSize(3)));
            
            D3d.UI.Ctrl.UpdateCurrentState();
            D3dIsOpen = true;
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.Controls.CloseRequestFcn = @(hObject,eventdata)D3d.UI.Controls('Controls_CloseRequestFcn',hObject,eventdata,guidata(hObject));
            app.s_ceil.ValueChangedFcn = @(hObject,eventdata)D3d.UI.Controls('s_ceil_Callback',hObject,eventdata,guidata(hObject));
            app.s_floor.ValueChangedFcn = @(hObject,eventdata)D3d.UI.Controls('s_floor_Callback',hObject,eventdata,guidata(hObject));
            app.s_alpha.ValueChangedFcn = @(hObject,eventdata)D3d.UI.Controls('s_alpha_Callback',hObject,eventdata,guidata(hObject));
            app.m_channelPicker.ValueChangedFcn = @(hObject,eventdata)D3d.UI.Controls('m_channelPicker_Callback',hObject,eventdata,guidata(hObject));
            app.pb_colorChooser.ButtonPushedFcn = @(hObject,eventdata)D3d.UI.Controls('pb_colorChooser_Callback',hObject,eventdata,guidata(hObject));
            app.cb_visible.ValueChangedFcn = @(hObject,eventdata)D3d.UI.Controls('cb_visible_Callback',hObject,eventdata,guidata(hObject));
            app.s_mids.ValueChangedFcn = @(hObject,eventdata)D3d.UI.Controls('s_mids_Callback',hObject,eventdata,guidata(hObject));
            app.cb_textureLighting.ValueChangedFcn = @(hObject,eventdata)D3d.UI.Controls('cb_textureLighting_Callback',hObject,eventdata,guidata(hObject));
            app.cb_segLighting.ValueChangedFcn = @(hObject,eventdata)D3d.UI.Controls('cb_segLighting_Callback',hObject,eventdata,guidata(hObject));
            app.cb_Play.ValueChangedFcn = @(hObject,eventdata)D3d.UI.Controls('cb_Play_Callback',hObject,eventdata,guidata(hObject));
            app.cb_Rotate.ValueChangedFcn = @(hObject,eventdata)D3d.UI.Controls('cb_Rotate_Callback',hObject,eventdata,guidata(hObject));
            app.pb_ResetView.ButtonPushedFcn = @(hObject,eventdata)D3d.UI.Controls('pb_ResetView_Callback',hObject,eventdata,guidata(hObject));
            app.cb_Wireframe.ValueChangedFcn = @(hObject,eventdata)D3d.UI.Controls('cb_Wireframe_Callback',hObject,eventdata,guidata(hObject));
            app.cb_SegmentationResults.ValueChangedFcn = @(hObject,eventdata)D3d.UI.Controls('cb_SegmentationResults_Callback',hObject,eventdata,guidata(hObject));
            app.cb_atten.ValueChangedFcn = @(hObject,eventdata)D3d.UI.Controls('cb_atten_Callback',hObject,eventdata,guidata(hObject));
            app.pb_saveSettings.ButtonPushedFcn = @(hObject,eventdata)D3d.UI.Controls('pb_saveSettings_Callback',hObject,eventdata,guidata(hObject));
            app.pb_loadSettings.ButtonPushedFcn = @(hObject,eventdata)D3d.UI.Controls('pb_loadSettings_Callback',hObject,eventdata,guidata(hObject));
            app.s_curFrame.ValueChangedFcn = @(hObject,eventdata)D3d.UI.Controls('s_curFrame_Callback',hObject,eventdata,guidata(hObject));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Controls_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to ViewerControls (see VARARGIN)
            
            % Choose default command line output for ViewerControls
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            setupData(app, hObject, handles, varargin);
            
            % UIWAIT makes ViewerControls wait for user response (see UIRESUME)
            % uiwait(handles.Controls);
        end

        % Value changed function: sw_buffer
        function sw_bufferValueChanged(app, event)
            value = app.sw_buffer.Value;
            D3d.UI.Ctrl.EnableBuffer(str2double(value));
            D3d.Update();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Controls = uifigure('Visible', 'off');
            app.Controls.Name = 'Controls';
            app.Controls.Tag = 'Controls';
            app.uipanel1 = uipanel(app.Controls);
            app.uipanel1.Title = 'Channel Specific Controls';
            app.uipanel1.Tag = 'uipanel1';
            app.s_ceil = uislider(app.uipanel1);
            app.s_ceil.Limits = [0 1];
            app.s_ceil.MajorTicks = [];
            app.s_ceil.MinorTicks = [];
            app.s_ceil.Tag = 's_ceil';
            app.s_ceil.Value = 1;
            app.s_floor = uislider(app.uipanel1);
            app.s_floor.Limits = [0 1];
            app.s_floor.MajorTicks = [];
            app.s_floor.MinorTicks = [];
            app.s_floor.Tag = 's_floor';
            app.s_alpha = uislider(app.uipanel1);
            app.s_alpha.Limits = [0 1];
            app.s_alpha.MajorTicks = [];
            app.s_alpha.MinorTicks = [];
            app.s_alpha.Tag = 's_alpha';
            app.s_alpha.Value = 1;
            app.m_channelPicker = uidropdown(app.uipanel1);
            app.m_channelPicker.Items = {'Channel 1', 'Channel 2', 'Channel 3'};
            app.m_channelPicker.Tag = 'm_channelPicker';
            app.m_channelPicker.Value = 'Channel 1';
            app.pb_colorChooser = uibutton(app.uipanel1, 'push');
            app.pb_colorChooser.Tag = 'pb_colorChooser';
            app.pb_colorChooser.Text = 'Color';
            app.cb_visible = uicheckbox(app.uipanel1);
            app.cb_visible.Tag = 'cb_visible';
            app.cb_visible.Text = 'Visible';
            app.text13 = uilabel(app.uipanel1);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Alpha';
            app.text14 = uilabel(app.uipanel1);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Low';
            app.text15 = uilabel(app.uipanel1);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Mid';
            app.text16 = uilabel(app.uipanel1);
            app.text16.Tag = 'text16';
            app.text16.Text = 'High';
            app.s_mids = uislider(app.uipanel1);
            app.s_mids.Limits = [0 1];
            app.s_mids.MajorTicks = [];
            app.s_mids.MinorTicks = [];
            app.s_mids.Tag = 's_mids';
            app.tb_title = uilabel(app.Controls);
            app.tb_title.Tag = 'tb_title';
            app.tb_title.Text = 'Title';
            app.uipanel3 = uipanel(app.Controls);
            app.uipanel3.Title = 'Metadata';
            app.uipanel3.Tag = 'uipanel3';
            app.text7 = uilabel(app.uipanel3);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Number of Frames:';
            app.text8 = uilabel(app.uipanel3);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Number of Channels:';
            app.text9 = uilabel(app.uipanel3);
            app.text9.Tag = 'text9';
            app.text9.Text = 'X Dimension:';
            app.text10 = uilabel(app.uipanel3);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Y Dimension:';
            app.text11 = uilabel(app.uipanel3);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Z Dimension:';
            app.text12 = uilabel(app.uipanel3);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Current Frame:';
            app.tb_curFrame = uilabel(app.uipanel3);
            app.tb_curFrame.Tag = 'tb_curFrame';
            app.tb_curFrame.Text = '1000';
            app.tb_numFrames = uilabel(app.uipanel3);
            app.tb_numFrames.Tag = 'tb_numFrames';
            app.tb_numFrames.Text = '1000';
            app.tb_numChan = uilabel(app.uipanel3);
            app.tb_numChan.Tag = 'tb_numChan';
            app.tb_numChan.Text = '1000';
            app.tb_zDim = uilabel(app.uipanel3);
            app.tb_zDim.Tag = 'tb_zDim';
            app.tb_zDim.Text = '1000';
            app.tb_yDim = uilabel(app.uipanel3);
            app.tb_yDim.Tag = 'tb_yDim';
            app.tb_yDim.Text = '1000';
            app.tb_xDim = uilabel(app.uipanel3);
            app.tb_xDim.Tag = 'tb_xDim';
            app.tb_xDim.Text = '1000';
            app.text25 = uilabel(app.uipanel3);
            app.text25.Tag = 'text25';
            app.text25.Text = 'Voxel Size in Y:';
            app.text26 = uilabel(app.uipanel3);
            app.text26.Tag = 'text26';
            app.text26.Text = 'Voxel Size in X:';
            app.text27 = uilabel(app.uipanel3);
            app.text27.Tag = 'text27';
            app.text27.Text = 'Voxel Size in Z:';
            app.tb_phyZ = uilabel(app.uipanel3);
            app.tb_phyZ.Tag = 'tb_phyZ';
            app.tb_phyZ.Text = '1000';
            app.tb_phyY = uilabel(app.uipanel3);
            app.tb_phyY.Tag = 'tb_phyY';
            app.tb_phyY.Text = '1000';
            app.tb_phyX = uilabel(app.uipanel3);
            app.tb_phyX.Tag = 'tb_phyX';
            app.tb_phyX.Text = '1000';
            app.uipanel4 = uipanel(app.Controls);
            app.uipanel4.Title = 'Global Controls';
            app.uipanel4.Tag = 'uipanel4';
            app.cb_textureLighting = uicheckbox(app.uipanel4);
            app.cb_textureLighting.Tag = 'cb_textureLighting';
            app.cb_textureLighting.Text = 'Texture Lighting';
            app.cb_segLighting = uicheckbox(app.uipanel4);
            app.cb_segLighting.Tag = 'cb_segLighting';
            app.cb_segLighting.Text = 'Polygon Lighting';
            app.cb_segLighting.Value = true;
            app.cb_Play = uicheckbox(app.uipanel4);
            app.cb_Play.Tag = 'cb_Play';
            app.cb_Play.Text = 'Play';
            app.cb_Rotate = uicheckbox(app.uipanel4);
            app.cb_Rotate.Tag = 'cb_Rotate';
            app.cb_Rotate.Text = 'Rotate';
            app.pb_ResetView = uibutton(app.uipanel4, 'push');
            app.pb_ResetView.Tag = 'pb_ResetView';
            app.pb_ResetView.Text = 'Reset View';
            app.cb_Wireframe = uicheckbox(app.uipanel4);
            app.cb_Wireframe.Tag = 'cb_Wireframe';
            app.cb_Wireframe.Text = 'Polygon Wireframe';
            app.cb_Wireframe.Value = true;
            app.cb_SegmentationResults = uicheckbox(app.uipanel4);
            app.cb_SegmentationResults.Tag = 'cb_SegmentationResults';
            app.cb_SegmentationResults.Text = 'Show Polygons';
            app.cb_SegmentationResults.Value = true;
            app.cb_atten = uicheckbox(app.uipanel4);
            app.cb_atten.Tag = 'cb_atten';
            app.cb_atten.Text = 'Light Attenuation';
            app.pannel_buffer = uipanel(app.uipanel4);
            app.pannel_buffer.Title = 'Show Texture Buffer';
            app.pannel_buffer.Tag = 'uipanel7';
            app.sw_buffer = uiswitch(app.pannel_buffer, 'slider');
            app.sw_buffer.Items = {'1', '2'};
            app.sw_buffer.ValueChangedFcn = createCallbackFcn(app, @sw_bufferValueChanged, true);
            app.sw_buffer.Enable = 'off';
            app.sw_buffer.Value = '1';
            app.pb_saveSettings = uibutton(app.uipanel4, 'push');
            app.pb_saveSettings.Tag = 'pb_saveSettings';
            app.pb_saveSettings.Text = 'Save Settings';
            app.pb_loadSettings = uibutton(app.uipanel4, 'push');
            app.pb_loadSettings.Tag = 'pb_loadSettings';
            app.pb_loadSettings.Text = 'Load Settings';
            app.text17 = uilabel(app.Controls);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Time';
            app.s_curFrame = uislider(app.Controls);
            app.s_curFrame.Limits = [0 1];
            app.s_curFrame.MajorTicks = [];
            app.s_curFrame.MinorTicks = [];
            app.s_curFrame.Tag = 's_curFrame';
            app.s_curFrame.Value = 1;
            app.funcPlot = uiaxes(app.Controls);
            app.funcPlot.XTick = [0 0.5 1];
            app.funcPlot.YTick = [0 0.5 1];
            app.funcPlot.Tag = 'funcPlot';
            % Show the figure after all components are created
            app.Controls.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Controls_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.Controls)

                % Execute the startup function
                runStartupFcn(app, @(app)Controls_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.Controls)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Controls)
        end
    end
end